classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ZkladyTab                     matlab.ui.container.Tab
        ZjednoduenSchmaLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        EnergiazmotoraEditField       matlab.ui.control.EditField
        EnergiazmotoraEditFieldLabel  matlab.ui.control.Label
        MechanickvkonEditField        matlab.ui.control.EditField
        MechanickvkonEditFieldLabel   matlab.ui.control.Label
        PrkonmotoraEditField          matlab.ui.control.EditField
        PrkonmotoraEditFieldLabel     matlab.ui.control.Label
        StratyvovedenEditField        matlab.ui.control.EditField
        StratyvovedenEditFieldLabel   matlab.ui.control.Label
        VkonzdrojaEditField_2         matlab.ui.control.EditField
        VkonzdrojaEditField_2Label    matlab.ui.control.Label
        VslednhodnotyLabel            matlab.ui.control.Label
        innospohonuEditField          matlab.ui.control.NumericEditField
        innospohonuEditFieldLabel     matlab.ui.control.Label
        OdporvedeniaEditField         matlab.ui.control.NumericEditField
        OdporvedeniaEditFieldLabel    matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        minEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        asSlider                      matlab.ui.control.Slider
        asSliderLabel                 matlab.ui.control.Label
        VEditField                    matlab.ui.control.NumericEditField
        VEditFieldLabel               matlab.ui.control.Label
        PrdSliderLabel                matlab.ui.control.Label
        PrdSlider                     matlab.ui.control.Slider
        NaptieSlider                  matlab.ui.control.Slider
        NaptieSliderLabel             matlab.ui.control.Label
        AEditField                    matlab.ui.control.NumericEditField
        AEditFieldLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        VykonPriPrem                  matlab.ui.control.UIAxes
        VkonovprofilTab               matlab.ui.container.Tab
        Trvanieprofilu0minLabel_3     matlab.ui.control.Label
        Celkovenergia0kWhLabel        matlab.ui.control.Label
        Priemernvkon0kWLabel          matlab.ui.control.Label
        TableCasVykon                 matlab.ui.control.Table
        AxesCumulatedEnergy           matlab.ui.control.UIAxes
        AxesPerformanceOverTime       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function prepocitajVsetko(app)
            U = app.VEditField.Value;
            I = app.AEditField.Value;
            R_mOhm = app.OdporvedeniaEditField.Value;
            eta_pct = app.innospohonuEditField.Value;
            t_min = app.minEditField.Value;
    
            vysledky = vzorce(U, I, R_mOhm, eta_pct, t_min);
    
            app.VkonzdrojaEditField_2.Value = sprintf('%.3f kW', vysledky.P_zdroja);
            app.StratyvovedenEditField.Value = sprintf('%.3f kW', vysledky.P_straty);
            app.PrkonmotoraEditField.Value = sprintf('%.3f kW', vysledky.P_motor);
            app.MechanickvkonEditField.Value = sprintf('%.3f kW', vysledky.P_mech);
            app.EnergiazmotoraEditField.Value  = sprintf('%.3f kWh', vysledky.E_zdroja);
                        
            I_vec = linspace(0,400,400);
            vysledky_graf = vzorce(U, I_vec, R_mOhm, eta_pct, t_min);
            mechY_vec = vysledky_graf.P_mech;
            mechY = vysledky.P_mech;

            hold(app.VykonPriPrem, 'off'); 
            hold(app.UIAxes, 'off')
            
            app.VykonPriPrem.YTickMode = 'auto';
            app.VykonPriPrem.YTickLabelMode = 'auto';

            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';

            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            

            % Nastavenie dynamického rozsahu Y osi
            maxY = max(vysledky_graf.P_zdroja);
            if maxY <= 0
                maxY = 10;
            end
            ylim(app.VykonPriPrem, [0, maxY * 1.1]);

            maxE = max(vysledky_graf.E_zdroja);
            maxT = max(t_min);
            if maxE <= 0
                maxE = 10; 
            end
            if maxT <= 10
                maxT = 10;
                app.UIAxes.XTick = 0 : 1 : maxT;
            else
                maxT = 60;
                app.UIAxes.XTick = 0 : 4 : maxT;
            end
            ylim(app.UIAxes, [0, maxE * 1.1]);
            xlim(app.UIAxes, [0, maxT])


            plot(app.VykonPriPrem, I_vec, vysledky_graf.P_zdroja, ...
                'LineWidth', 2, 'DisplayName', 'Zdroj', ...
                'Color','b');
            hold(app.VykonPriPrem, "on");
            plot(app.VykonPriPrem, I, vysledky.P_zdroja, 'o', ...
                'MarkerSize', 7, 'HandleVisibility','on', ...
                'DisplayName', 'Aktuálna hodnota prúdu', ...
                'Color','b', 'MarkerFaceColor', 'b')
            
            plot(app.VykonPriPrem, I_vec, mechY_vec, 'LineWidth', 2, ...
                'DisplayName', 'Mechanický výstup', 'Color', 'Red')
            plot(app.VykonPriPrem, I, mechY, 'o', 'MarkerSize', 7, ...
                'HandleVisibility','on', 'DisplayName', 'Aktuálna hodnota prúdu', ...
                'Color','r','MarkerFaceColor','r')
            legend(app.VykonPriPrem, 'Location', 'northwest');
            
            t_vec = linspace(0,60,200);
            vysledky_graf = vzorce(U, I, R_mOhm, eta_pct, t_vec);

            hold(app.UIAxes, 'off');
            cla(app.UIAxes);

            plot(app.UIAxes, t_vec, vysledky_graf.E_zdroja, 'LineWidth', 2, 'Color', [0.12, 0.53, 0.90]);
            hold(app.UIAxes, 'on');

            plot(app.UIAxes, t_min, vysledky.E_zdroja, 'o', 'MarkerSize', 8, ...
            'MarkerFaceColor', [0.12, 0.53, 0.90], ...
            'MarkerEdgeColor', [0.12, 0.53, 0.90]);

        end
        
        
        function prepocitajVsetkoPg2(app, data)

            if isempty(data) || size(data, 2) < 2
                return;
            end

            data = sortrows(data, 1);

            t_min = data(:, 1);
            P_kW = data(:, 2);
            t_hodiny = t_min / 60;
            E_kWh = cumtrapz(t_hodiny, P_kW);
            avgPerf = round(mean(P_kW));

            app.Trvanieprofilu0minLabel_3.Text = ["Trvanie profilu:"; sprintf('%d min', max(t_min))];
            app.Priemernvkon0kWLabel.Text = ["Priemerný výkon"; sprintf('%.2f kW', avgPerf)];
            app.Celkovenergia0kWhLabel.Text = ["Celková energia:"; sprintf('%.3f kWh', max(E_kWh))];

            hold(app.AxesPerformanceOverTime, 'off');
            plot(app.AxesPerformanceOverTime, t_min, P_kW, '-o', 'LineWidth', 2, 'MarkerSize', 6, 'Color', 'b', 'MarkerFaceColor', 'b');
            grid(app.AxesPerformanceOverTime, 'on');
            hold(app.AxesPerformanceOverTime, "on");
            textAvg = sprintf('Priemer: %d kW   ', avgPerf);


            yline(app.AxesPerformanceOverTime, avgPerf, '-', textAvg, ...
                'Color', [0.5, 0.5, 0.5, 0.4], ... 
                'LineWidth', 2, ...
                'LabelHorizontalAlignment', 'right', ...
                'LabelVerticalAlignment', 'top', ... 
                'FontSize', 10, ...
                'LineStyle', '--');

            if ~isempty(t_min) && max(t_min) > 0
                xlim(app.AxesPerformanceOverTime, [0, max(t_min) * 1.1]);
                maxY = max(P_kW);
                if maxY == 0
                    maxY = 10; 
                end
                ylim(app.AxesPerformanceOverTime, [0, maxY * 1.2]);
            end
            
            hold(app.AxesCumulatedEnergy, 'off');
            plot(app.AxesCumulatedEnergy, t_min, E_kWh, '-o', 'LineWidth', 2, 'MarkerSize', 6, 'Color', [0.12, 0.53, 0.90], 'MarkerFaceColor', [0.12, 0.53, 0.90]);
            grid(app.AxesCumulatedEnergy, 'on');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.prepocitajVsetko();

            app.TableCasVykon.Data = strings(8,2);
            
        end

        % Value changed function: VEditField
        function VEditFieldValueChanged(app, event)
            value = app.VEditField.Value;
            app.NaptieSlider.Value = value;
            app.prepocitajVsetko();
        end

        % Value changing function: NaptieSlider
        function NaptieSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VEditField.Value = changingValue;
            app.prepocitajVsetko();
        end

        % Value changed function: NaptieSlider
        function NaptieSliderValueChanged(app, event)
            value = app.NaptieSlider.Value;
            app.VEditField.Value = value;
            app.prepocitajVsetko();
        end

        % Value changed function: PrdSlider
        function PrdSliderValueChanged(app, event)
            value = app.PrdSlider.Value;
            app.AEditField.Value = value;
            app.prepocitajVsetko();
                       
        end

        % Value changing function: PrdSlider
        function PrdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AEditField.Value = changingValue;
            app.prepocitajVsetko();
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            value = app.AEditField.Value;
            app.PrdSlider.Value = value;
            app.prepocitajVsetko();
        end

        % Value changed function: asSlider
        function asSliderValueChanged(app, event)
            value = app.asSlider.Value;
            app.minEditField.Value = value;
            app.prepocitajVsetko();
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            value = app.minEditField.Value;
            app.asSlider.Value = value;
            app.prepocitajVsetko();
        end

        % Value changing function: asSlider
        function asSliderValueChanging(app, event)
            changingValue = event.Value;
            app.minEditField.Value = changingValue;
            app.prepocitajVsetko();
        end

        % Value changed function: innospohonuEditField
        function innospohonuEditFieldValueChanged(app, event)
            value = app.innospohonuEditField.Value;
            app.prepocitajVsetko();
        end

        % Value changed function: OdporvedeniaEditField
        function OdporvedeniaEditFieldValueChanged(app, event)
            value = app.OdporvedeniaEditField.Value;
            app.prepocitajVsetko();
        end

        % Button down function: TableCasVykon
        function TableCasVykonButtonDown(app, event)

        end

        % Cell edit callback: TableCasVykon
        function TableCasVykonCellEdit(app, event)
            row = event.Indices(1);
    
            timeStr = string(app.TableCasVykon.Data(row, 1));
            powerStr = string(app.TableCasVykon.Data(row, 2));
            
            currentData = string(app.TableCasVykon.Data);
            emptyRows = sum(currentData == "", 2) == 2;
            
            if ~any(emptyRows)
                newRow = ["", ""]; 
                app.TableCasVykon.Data = [currentData; newRow];
            end
            
            if timeStr ~= "" && powerStr ~= ""
                
                dataStr = string(app.TableCasVykon.Data);
                dataNum = str2double(dataStr);
                
                isEmpty = (dataStr == ""); 
                isInvalidText = isnan(dataNum) & ~isEmpty;
                
                if any(isInvalidText, 'all')
                    uialert(app.UIFigure, 'Invalid character in the table. Enter numbers only.', 'Input Error');
                    return;
                end
                
                validColCount = sum(~isnan(dataNum), 2);
                validRows = (validColCount == 2);
                cleanData = dataNum(validRows, :);
                
                app.prepocitajVsetkoPg2(cleanData);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ZkladyTab = uitab(app.TabGroup);
            app.ZkladyTab.Title = 'Základy';
            app.VykonPriPrem = uiaxes(app.ZkladyTab);
            title(app.VykonPriPrem, 'Výkon pri premenlivom prúde')
            xlabel(app.VykonPriPrem, 'Prúd [A]')
            ylabel(app.VykonPriPrem, 'Výkon [kW]')
            app.VykonPriPrem.XTick = [0 50 100 150 200 250 300 350 400];
            app.VykonPriPrem.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'; '400'};
            app.VykonPriPrem.YTick = [];
            app.VykonPriPrem.YTickLabel = '';
            app.UIAxes = uiaxes(app.ZkladyTab);
            title(app.UIAxes, 'Energia pri konštantnom výkone ')
            xlabel(app.UIAxes, 'Čas [min]')
            ylabel(app.UIAxes, 'Energia [kWh]')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.AEditFieldLabel = uilabel(app.ZkladyTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.ZkladyTab, 'numeric');
            app.AEditField.Limits = [100 400];
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.AEditField.Value = 100;
            app.NaptieSliderLabel = uilabel(app.ZkladyTab);
            app.NaptieSliderLabel.Text = 'Napätie';
            app.NaptieSlider = uislider(app.ZkladyTab);
            app.NaptieSlider.Limits = [100 800];
            app.NaptieSlider.MajorTicks = [100 200 300 400 500 600 700 800];
            app.NaptieSlider.ValueChangedFcn = createCallbackFcn(app, @NaptieSliderValueChanged, true);
            app.NaptieSlider.ValueChangingFcn = createCallbackFcn(app, @NaptieSliderValueChanging, true);
            app.NaptieSlider.Step = 1;
            app.NaptieSlider.Value = 100;
            app.PrdSlider = uislider(app.ZkladyTab);
            app.PrdSlider.Limits = [100 400];
            app.PrdSlider.MajorTicks = [100 200 300 400];
            app.PrdSlider.ValueChangedFcn = createCallbackFcn(app, @PrdSliderValueChanged, true);
            app.PrdSlider.ValueChangingFcn = createCallbackFcn(app, @PrdSliderValueChanging, true);
            app.PrdSlider.Step = 1;
            app.PrdSlider.Value = 100;
            app.PrdSliderLabel = uilabel(app.ZkladyTab);
            app.PrdSliderLabel.Text = 'Prúd';
            app.VEditFieldLabel = uilabel(app.ZkladyTab);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.ZkladyTab, 'numeric');
            app.VEditField.Limits = [100 800];
            app.VEditField.ValueChangedFcn = createCallbackFcn(app, @VEditFieldValueChanged, true);
            app.VEditField.Value = 100;
            app.asSliderLabel = uilabel(app.ZkladyTab);
            app.asSliderLabel.Text = 'Čas';
            app.asSlider = uislider(app.ZkladyTab);
            app.asSlider.Limits = [0 60];
            app.asSlider.MajorTicks = [0 10 20 30 40 50 60];
            app.asSlider.ValueChangedFcn = createCallbackFcn(app, @asSliderValueChanged, true);
            app.asSlider.ValueChangingFcn = createCallbackFcn(app, @asSliderValueChanging, true);
            app.asSlider.Step = 0.2;
            app.minEditField = uieditfield(app.ZkladyTab, 'numeric');
            app.minEditField.Limits = [0 60];
            app.minEditField.ValueDisplayFormat = '%5.2f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditFieldLabel = uilabel(app.ZkladyTab);
            app.minEditFieldLabel.Text = 'min';
            app.mLabel = uilabel(app.ZkladyTab);
            app.mLabel.Text = 'mΩ';
            app.Label = uilabel(app.ZkladyTab);
            app.Label.Text = '%';
            app.OdporvedeniaEditFieldLabel = uilabel(app.ZkladyTab);
            app.OdporvedeniaEditFieldLabel.Text = 'Odpor vedenia';
            app.OdporvedeniaEditField = uieditfield(app.ZkladyTab, 'numeric');
            app.OdporvedeniaEditField.Limits = [0 100];
            app.OdporvedeniaEditField.ValueChangedFcn = createCallbackFcn(app, @OdporvedeniaEditFieldValueChanged, true);
            app.innospohonuEditFieldLabel = uilabel(app.ZkladyTab);
            app.innospohonuEditFieldLabel.Text = 'Účinnosť pohonu';
            app.innospohonuEditField = uieditfield(app.ZkladyTab, 'numeric');
            app.innospohonuEditField.Limits = [0 100];
            app.innospohonuEditField.ValueChangedFcn = createCallbackFcn(app, @innospohonuEditFieldValueChanged, true);
            app.VslednhodnotyLabel = uilabel(app.ZkladyTab);
            app.VslednhodnotyLabel.Text = 'Výsledné hodnoty';
            app.VkonzdrojaEditField_2Label = uilabel(app.ZkladyTab);
            app.VkonzdrojaEditField_2Label.Text = 'Výkon zdroja';
            app.VkonzdrojaEditField_2 = uieditfield(app.ZkladyTab, 'text');
            app.VkonzdrojaEditField_2.Editable = 'off';
            app.StratyvovedenEditFieldLabel = uilabel(app.ZkladyTab);
            app.StratyvovedenEditFieldLabel.Text = 'Straty vo vedení';
            app.StratyvovedenEditField = uieditfield(app.ZkladyTab, 'text');
            app.StratyvovedenEditField.Editable = 'off';
            app.PrkonmotoraEditFieldLabel = uilabel(app.ZkladyTab);
            app.PrkonmotoraEditFieldLabel.Text = 'Príkon motora';
            app.PrkonmotoraEditField = uieditfield(app.ZkladyTab, 'text');
            app.PrkonmotoraEditField.Editable = 'off';
            app.MechanickvkonEditFieldLabel = uilabel(app.ZkladyTab);
            app.MechanickvkonEditFieldLabel.Text = 'Mechanický výkon';
            app.MechanickvkonEditField = uieditfield(app.ZkladyTab, 'text');
            app.MechanickvkonEditField.Editable = 'off';
            app.EnergiazmotoraEditFieldLabel = uilabel(app.ZkladyTab);
            app.EnergiazmotoraEditFieldLabel.Text = 'Energia z motora';
            app.EnergiazmotoraEditField = uieditfield(app.ZkladyTab, 'text');
            app.EnergiazmotoraEditField.Editable = 'off';
            app.Image = uiimage(app.ZkladyTab);
            app.Image.ImageSource = 'emobAPP_FOTO.png';
            app.ZjednoduenSchmaLabel = uilabel(app.ZkladyTab);
            app.ZjednoduenSchmaLabel.Text = 'Zjednodušená Schéma';
            app.VkonovprofilTab = uitab(app.TabGroup);
            app.VkonovprofilTab.Title = 'Výkonový profil';
            app.AxesPerformanceOverTime = uiaxes(app.VkonovprofilTab);
            title(app.AxesPerformanceOverTime, 'Výkon v čase')
            xlabel(app.AxesPerformanceOverTime, 'Čas [min]')
            ylabel(app.AxesPerformanceOverTime, 'Výkon [kW]')
            zlabel(app.AxesPerformanceOverTime, 'Z')
            app.AxesPerformanceOverTime.XTick = [];
            app.AxesCumulatedEnergy = uiaxes(app.VkonovprofilTab);
            title(app.AxesCumulatedEnergy, 'Kumulovaná energia')
            xlabel(app.AxesCumulatedEnergy, 'Čas [min]')
            ylabel(app.AxesCumulatedEnergy, 'Energia [kWh]')
            zlabel(app.AxesCumulatedEnergy, 'Z')
            app.TableCasVykon = uitable(app.VkonovprofilTab);
            app.TableCasVykon.ColumnName = {'Čas [min]'; 'Výkon [kW]'};
            app.TableCasVykon.RowName = {};
            app.TableCasVykon.ColumnSortable = [true false];
            app.TableCasVykon.ColumnEditable = true;
            app.TableCasVykon.CellEditCallback = createCallbackFcn(app, @TableCasVykonCellEdit, true);
            app.TableCasVykon.ButtonDownFcn = createCallbackFcn(app, @TableCasVykonButtonDown, true);
            app.Priemernvkon0kWLabel = uilabel(app.VkonovprofilTab);
            app.Priemernvkon0kWLabel.Text = 'Priemerný výkon: 0 kW';
            app.Celkovenergia0kWhLabel = uilabel(app.VkonovprofilTab);
            app.Celkovenergia0kWhLabel.Text = 'Celková energia: 0 kWh';
            app.Trvanieprofilu0minLabel_3 = uilabel(app.VkonovprofilTab);
            app.Trvanieprofilu0minLabel_3.Text = 'Trvanie profilu: 0 min';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end